classdef RasterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        pairedEditFieldLabel      matlab.ui.control.Label
        pairedEditField           matlab.ui.control.NumericEditField
        decintrialEditFieldLabel  matlab.ui.control.Label
        decintrialEditField       matlab.ui.control.NumericEditField
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Timestamps = evalin('base', 'ChoiceGoalTimestamps')
        Paired
        Trial
        DecInTrial
    end
    
    methods (Access = public)
        
        function rasterplot(app, times, numtrials, triallen, varargin)
            %Rajiv Narayan (2020). Rasterplot 
            % (https://www.mathworks.com/matlabcentral/fileexchange/10000-rasterplot),
            % MATLAB Central File Exchange. Retrieved July 22, 2020.
            nin=nargin;
            %%%%%%%%%%%%%% Plot variables %%%%%%%%%%%%%%
            plotwidth=1;     % spike thickness
            plotcolor='k';   % spike color
            trialgap=1.1;    % distance between trials
            defaultfs=1000;  % default sampling rate
            showtimescale=1; % display timescale
            showlabels=0;    % display x and y labels
            %%%%%%%%% Code Begins %%%%%%%%%%%%
            switch nin
                case 4 %no handle so plot in a separate figure
                    figure;
                    hresp=gca;
                    fs=defaultfs;
                case 5 %handle supplied
                    hresp=varargin{1};
                    if (~ishandle(hresp))
                        error('Invalid handle');
                    end
                    fs=defaultfs;
                case 6 %fs supplied
                    hresp=varargin{1};
                    if (~ishandle(hresp))
                        error('Invalid handle');
                    end
                    fs = varargin{2};        
                otherwise
                    error ('Invalid Arguments');
            end
            % plot spikes
            trials=ceil(times/triallen);
            reltimes=mod(times,triallen);
            reltimes(~reltimes)=triallen;
            numspikes=length(times);
            xx=ones(3*numspikes,1)*nan;
            yy=ones(3*numspikes,1)*nan;
            yy(1:3:3*numspikes)=(trials-1)*trialgap;
            yy(2:3:3*numspikes)=yy(1:3:3*numspikes)+1;
  
            %scale the time axis to ms
            xx(1:3:3*numspikes)=reltimes*1000/fs;
            xx(2:3:3*numspikes)=reltimes*1000/fs;
            xlim=[1,triallen*1000/fs];
            h=plot(app.UIAxes, xx, yy, plotcolor, 'linewidth',plotwidth);
            axis(app.UIAxes, [xlim,0,(numtrials)*1.5]);  
  
            if (showtimescale)
                set(hresp, 'ytick', [],'tickdir','out');        
            else
                set(hresp,'ytick',[],'xtick',[]);
            end
  
            if (showlabels)
                xlabel('Time(ms)');
                ylabel('Trials');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: pairedEditField
        function pairedEditFieldValueChanged(app, event)
            app.Paired = app.pairedEditField.Value;
            
        end

        % Value changed function: decintrialEditField
        function decintrialEditFieldValueChanged(app, event)
            app.DecInTrial = app.decintrialEditField.Value;
            decnum = size(app.Timestamps(app.Paired).Decreasers, 2);
            if decnum ~= 1
                decindex = mod(app.DecInTrial, decnum);
                if decindex == 0
                    decindex = decnum;
                end
            else
                decindex = 1;
            end
            incindex = ceil(app.DecInTrial/decnum);
            trials = size(app.Timestamps(app.Paired).Decreasers{1, decindex}, 2);
            app.UITable.Data = trials;
            trialstoplot = ceil(rand(1, 5)*trials);
            for emptycheck = 1:5
                if app.Timestamps(app.Paired).Decreasers{1, decindex}{1, emptycheck)} == 0 || app.Timestamps(app.Paired).Decreasers{1, emptycheck} == 0
                    if ~ismember(trialstoplot+1, trialstoplot) && trialstoplot ~= trials
                        try
                            if app.Timestamps(app.Paired).Decreasers{1, decindex}{1, trialstoplot(emptycheck+1)} ~= 0 && app.Timestamps(app.Paired).Increasers{1, incindex}{1, trialstoplot(emptycheck+1)} ~= 0
                                trialstoplot(emptycheck) = trialstoplot(emptycheck)+1;
                            elseif app.Timestamps(app.Paired).Decreasers{1, decindex}{1, trialstoplot(emptycheck-1)} ~= 0 && app.Timestamps(app.Paired).Increasers{1, incindex}{1, trialstoplot(emptycheck-1)} ~= 0
                                trialstoplot(emptycheck) = trialstoplot(emptycheck)-1;
                            end
                        catch
                            
                        end
                    end
                end
            end
        end

        % Callback function
        function trialnoEditFieldValueChanged(app, event)
            app.Trial = app.trialnoEditField.Value;
            decnum = size(app.Timestamps(app.Paired).Decreasers, 2);
            incnum = size(app.Timestamps(app.Paired).Increasers, 2);
            if decnum ~= 1
                decindex= mod(app.DecInTrial, decnum);
                if decindex == 0
                    decindex = decnum;
                end
            else
                decindex = 1;
            end
            
            incindex = ceil(app.DecInTrial/decnum);
            if app.Timestamps(app.Paired).Decreasers{1, decindex}{1, app.Trial} ~= 0
                decstamps = app.Timestamps(app.Paired).Decreasers{1, decindex}{1, app.Trial}-app.Timestamps(app.Paired).Decreasers{1, decindex}{1, app.Trial}(1);   
            end
            if app.Timestamps(app.Paired).Increasers{1, incindex}{1, app.Trial} ~= 0
                incstamps = app.Timestamps(app.Paired).Increasers{1, incindex}{1, app.Trial}-app.Timestamps(app.Paired).Increasers{1, incindex}{1, app.Trial}(1)+15000000;
            end
            if app.Timestamps(app.Paired).Decreasers{1, decindex}{1, app.Trial} ~= 0
                comparestamps = cat(2, decstamps, incstamps);
                rasterplot(app, comparestamps, 2, 15000000, app.UIAxes)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raster Comparison')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.pairedEditFieldLabel = uilabel(app.UIFigure);
            app.pairedEditFieldLabel.Text = 'paired';
            app.pairedEditField = uieditfield(app.UIFigure, 'numeric');
            app.pairedEditField.ValueChangedFcn = createCallbackFcn(app, @pairedEditFieldValueChanged, true);
            app.decintrialEditFieldLabel = uilabel(app.UIFigure);
            app.decintrialEditFieldLabel.Text = 'decintrial';
            app.decintrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.decintrialEditField.ValueChangedFcn = createCallbackFcn(app, @decintrialEditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'X-Corr')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '10 ms lags')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Component Analysis')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Mean Firing Rate')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RasterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end